classdef FinalApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MedicareAnalysisAppUIFigure  matlab.ui.Figure
        Panel                        matlab.ui.container.Panel
        AreaButtonGroup              matlab.ui.container.ButtonGroup
        WestButton                   matlab.ui.control.RadioButton
        MidwestButton                matlab.ui.control.RadioButton
        NortheastButton              matlab.ui.control.RadioButton
        SouthButton                  matlab.ui.control.RadioButton
        USATop20Button               matlab.ui.control.RadioButton
        SortingOptionButtonGroup     matlab.ui.container.ButtonGroup
        AscendingButton              matlab.ui.control.ToggleButton
        DescendingButton             matlab.ui.control.ToggleButton
        DropDown                     matlab.ui.control.DropDown
        DisplayButton                matlab.ui.control.Button
        SaveasTextFileButton         matlab.ui.control.Button
        RunButton                    matlab.ui.control.Button
        Panel_2                      matlab.ui.container.Panel
        MedicareDataAnalysisLabel    matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        UITable                      matlab.ui.control.Table
        AverageLabel                 matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        Lamp                         matlab.ui.control.Lamp
        PopButton                    matlab.ui.control.Button
    end

    properties (Access = public)
        area, state, val,order;
    end
    
    methods (Access = private)
        
        function pick(app)
            if app.WestButton.Value == true
                app.area = 'West';
            elseif app.MidwestButton.Value == true
                app.area = 'Midwest';
            elseif app.NortheastButton.Value == true
                app.area = 'Northeast';
            elseif app.SouthButton.Value == true
                app.area = 'South';
            else
                app.area = 'USA';
            end
        end
        
        function ord(app)
            if app.AscendingButton.Value == true
                app.order = 'Ascending';
            else
                app.order = 'Descending';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunProg(app, event)
            app.Lamp.Color = [1 0 0];
            app.Label.Text = 'Reading Dataset...';
            drawnow
            pick(app)
            [app.val, app.state] = runmain(app);
            dat = cell(length(app.state),2);
            if strcmp(app.DropDown.Value,'# of Different Treatment')
                for i = 1:length(app.state)
                    dat{i,1} = app.state(i,1:2);
                    dat{i,2} = sprintf('%5.0f', app.val(i));
                end
            else
                for i = 1:length(app.state)
                    dat{i,1} = app.state(i,1:2);
                    dat{i,2} = sprintf('$ %10.2f', app.val(i));
                end
            end
            set(app.UITable, 'Data', dat);
            if strcmp(app.DropDown.Value, '# of Different Treatment')
                app.AverageLabel.Text = sprintf('Average: %.0f', calcmean(app.val));
            else
                app.AverageLabel.Text = sprintf('Average: $ %.2f', calcmean(app.val));
            end
            app.Label.Text = 'Done!';
            app.Lamp.Color = [0 1 0];
            if strcmp(app.DisplayButton.Text, 'Table Display')
                fig = app.Panel_2;
                fig.Children = flip(fig.Children);
                app.DisplayButton.Text = 'Graph Display';
                drawnow
            end
            app.DisplayButton.Enable = true;
            app.SaveasTextFileButton.Enable = true;
        end

        % Button pushed function: DisplayButton
        function Disp(app, event)
            y = app.val;
            xtemp = 1:(length(app.val));
            x = cell(1,length(app.state));
            for i = 1:length(app.state)
                x{i} = app.state(i,1:2);
            end
	    bar(app.UIAxes, xtemp, y);
            app.UIAxes.YAxis.Exponent = 0;
            tit = sprintf('%s %s Bar Plot', app.area, app.DropDown.Value);
            title(app.UIAxes, tit);
            xticks(app.UIAxes, xtemp);
	    xticklabels(app.UIAxes, x);
            xlabel(app.UIAxes,'State');
            ylabel(app.UIAxes,'Value');
            if strcmp(app.DropDown.Value, '# of Different Treatment')
                ylim(app.UIAxes, [0 110]);
            else
                app.UIAxes.YLimMode = 'auto';
            end
            if strcmp(app.DisplayButton.Text, 'Graph Display')
                fig = app.Panel_2;
                fig.Children = flip(fig.Children);
                app.DisplayButton.Text = 'Table Display';
                app.PopButton.Visible = 'on';
                drawnow
            else
                fig = app.Panel_2;
                fig.Children = flip(fig.Children);
                app.DisplayButton.Text = 'Graph Display';
                app.PopButton.Visible = 'off';
                drawnow
            end
        end

        % Button pushed function: SaveasTextFileButton
        function TxtSave(app, event)
            ord(app)
            txtsave(app.state, app.val, app.DropDown.Value, app.area, app.order)
        end

        % Button pushed function: PopButton
        function PopButtonPushed(app, event)
            creategraph(app.state, app.val, app.DropDown.Value, app.area)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MedicareAnalysisAppUIFigure = uifigure('Visible', 'off');
            app.MedicareAnalysisAppUIFigure.Name = 'Medicare Analysis App';
            app.Panel = uipanel(app.MedicareAnalysisAppUIFigure);
            app.AreaButtonGroup = uibuttongroup(app.Panel);
            app.AreaButtonGroup.Title = 'Area';
            app.WestButton = uiradiobutton(app.AreaButtonGroup);
            app.WestButton.Text = 'West';
            app.MidwestButton = uiradiobutton(app.AreaButtonGroup);
            app.MidwestButton.Text = 'Midwest';
            app.NortheastButton = uiradiobutton(app.AreaButtonGroup);
            app.NortheastButton.Text = 'Northeast';
            app.SouthButton = uiradiobutton(app.AreaButtonGroup);
            app.SouthButton.Text = 'South';
            app.USATop20Button = uiradiobutton(app.AreaButtonGroup);
            app.USATop20Button.Text = 'U.S.A (Top 20)';
            app.USATop20Button.Value = true;
            app.SortingOptionButtonGroup = uibuttongroup(app.Panel);
            app.SortingOptionButtonGroup.Title = 'Sorting Option';
            app.AscendingButton = uitogglebutton(app.SortingOptionButtonGroup);
            app.AscendingButton.Text = 'Ascending';
            app.AscendingButton.Value = true;
            app.DescendingButton = uitogglebutton(app.SortingOptionButtonGroup);
            app.DescendingButton.Text = 'Descending';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'Coverage Charge', 'Patient Payment', '# of Different Treatment'};
            app.DropDown.Value = 'Coverage Charge';
            app.DisplayButton = uibutton(app.Panel, 'push');
            app.DisplayButton.ButtonPushedFcn = createCallbackFcn(app, @Disp, true);
            app.DisplayButton.Enable = 'off';
            app.DisplayButton.Text = 'Graph Display';
            app.SaveasTextFileButton = uibutton(app.Panel, 'push');
            app.SaveasTextFileButton.ButtonPushedFcn = createCallbackFcn(app, @TxtSave, true);
            app.SaveasTextFileButton.Enable = 'off';
            app.SaveasTextFileButton.Text = 'Save as Text File';
            app.RunButton = uibutton(app.Panel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunProg, true);
            app.RunButton.Text = 'Run';
            app.Panel_2 = uipanel(app.MedicareAnalysisAppUIFigure);
            app.MedicareDataAnalysisLabel = uilabel(app.Panel_2);
            app.MedicareDataAnalysisLabel.Text = 'Medicare Data Analysis';
            app.UIAxes = uiaxes(app.Panel_2);
            title(app.UIAxes, '...')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UITable = uitable(app.Panel_2);
            app.UITable.ColumnName = {'State'; 'Value'};
            app.UITable.RowName = {};
            app.AverageLabel = uilabel(app.MedicareAnalysisAppUIFigure);
            app.AverageLabel.Text = 'Average: ...';
            app.Label = uilabel(app.MedicareAnalysisAppUIFigure);
            app.Label.Text = '...';
            app.Lamp = uilamp(app.MedicareAnalysisAppUIFigure);
            app.PopButton = uibutton(app.MedicareAnalysisAppUIFigure, 'push');
            app.PopButton.ButtonPushedFcn = createCallbackFcn(app, @PopButtonPushed, true);
            app.PopButton.Visible = 'off';
            app.PopButton.Text = {'Pop-Out Window'; 'for better visual'};
            % Show the figure after all components are created
            app.MedicareAnalysisAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MedicareAnalysisAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MedicareAnalysisAppUIFigure)
        end
    end
end